classdef electronic_load_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ActualOutputsLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        Image                       matlab.ui.control.Image
        CurrentASlider              matlab.ui.control.Slider
        CurrentASliderLabel         matlab.ui.control.Label
        PowerWSlider                matlab.ui.control.Slider
        PowerWSliderLabel           matlab.ui.control.Label
        ModeofOperationSwitch       matlab.ui.control.Switch
        ModeofOperationSwitchLabel  matlab.ui.control.Label
        SetParametersButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            power = app.PowerWSlider.Value
            current = app.CurrentASlider.Value
            %This doesn't work for Current-Controlled
            %Think it's because the two strings are different lengths
            if app.ModeofOperationSwitch.Value == 'Power-Controlled'
                mode = 'P'
                txt = jsonencode(power, "PrettyPrint", true)
            else 
                mode = 'C'
                txt = jsonencode(power, "PrettyPrint", true)
            end
            
            app.UITable.Data = [1,2,3] %Change to actual data read from arduino
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetParametersButton = uibutton(app.UIFigure, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.ModeofOperationSwitchLabel = uilabel(app.UIFigure);
            app.ModeofOperationSwitchLabel.Text = 'Mode of Operation';
            app.ModeofOperationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeofOperationSwitch.Items = {'Power-Controlled', 'Current-Controlled'};
            app.ModeofOperationSwitch.Value = 'Power-Controlled';
            app.PowerWSliderLabel = uilabel(app.UIFigure);
            app.PowerWSliderLabel.Text = 'Power(W)';
            app.PowerWSlider = uislider(app.UIFigure);
            app.CurrentASliderLabel = uilabel(app.UIFigure);
            app.CurrentASliderLabel.Text = 'Current(A)';
            app.CurrentASlider = uislider(app.UIFigure);
            app.CurrentASlider.Limits = [0 5];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SUBSEE.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Current(A)'; 'Power(W)'; 'Voltage(V)'};
            app.UITable.RowName = {};
            app.ActualOutputsLabel = uilabel(app.UIFigure);
            app.ActualOutputsLabel.Text = 'Actual Outputs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electronic_load_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end